classdef StrucIDAppMatlab2024b_3_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StrucIDWindowUIFigure        matlab.ui.Figure
        output_2                     matlab.ui.container.Panel
        UITablePar                   matlab.ui.control.Table
        UITableInput                 matlab.ui.control.Table
        UITableOutput                matlab.ui.control.Table
        output                       matlab.ui.container.Panel
        idenresult_4                 matlab.ui.control.NumericEditField
        CorrelatedParametersLabel_2  matlab.ui.control.Label
        idenresult_3                 matlab.ui.control.NumericEditField
        GapSizeLabel_2               matlab.ui.control.Label
        idenresult_2                 matlab.ui.control.NumericEditField
        ZeroSingularValuesEditFieldLabel  matlab.ui.control.Label
        idenresult                   matlab.ui.control.TextArea
        input                        matlab.ui.container.Panel
        T_simEditField               matlab.ui.control.NumericEditField
        T_simEditFieldLabel          matlab.ui.control.Label
        TrajectoriesEditField        matlab.ui.control.NumericEditField
        TrajectoriesEditFieldLabel   matlab.ui.control.Label
        FixNaNsButton                matlab.ui.control.StateButton
        SaveButton                   matlab.ui.control.Button
        IncludeAdjointSensCheckBox   matlab.ui.control.CheckBox
        InputNumberEditField         matlab.ui.control.NumericEditField
        InputTextEditField           matlab.ui.control.EditField
        TypeOfAnalysisDropDown       matlab.ui.control.DropDown
        IntegratorDropDown           matlab.ui.control.DropDown
        IntegratorDropDownLabel      matlab.ui.control.Label
        AbsToleranceEditField        matlab.ui.control.NumericEditField
        AbsToleranceEditFieldLabel   matlab.ui.control.Label
        T_fEditField                 matlab.ui.control.NumericEditField
        T_fLabel                     matlab.ui.control.Label
        TypeofAnalysisButtonGroup    matlab.ui.container.ButtonGroup
        LocalControllabilityButton   matlab.ui.control.RadioButton
        LocalObservabilityButton     matlab.ui.control.RadioButton
        ImportModelButton            matlab.ui.control.Button
        SensitivityType              matlab.ui.container.ButtonGroup
        AbsoluteSensButton           matlab.ui.control.RadioButton
        RelativeSensButton           matlab.ui.control.RadioButton
        AnalyseButton                matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        StrucID_Label                matlab.ui.control.Label
    end


    properties (Access = public)
        StrucIDModel % contains the model in a struct variable
        StrucIDOptions % summarizes the options for integration, etc
        thetaRealised % values for parameters in the last RUN of the model
        CorParIndex % Array with indices of correlated parameters
        FirstSelectionPar % selected parameters upon entering
        FirstSelectionFixed % selected fixed parameters upon entering
        FirstSelectionSensors % selected sensors upon entering
        FirstSelectionInputs % selected input variables upon entering
        FirstValuesThetaNom % first values of parameters upon entering
        StrucIDModelName % string with name model
        ResultsDirectory % This is where results are store in case of a save
        resultsAnalysis % store the variables in this structure
        ModelDirectory % This is where the models are stored
    end

    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StrucIDWindowUIFigure.Icon=which('icon_48.png');
            app.idenresult.Value = 'Start by clicking on the Import Model button...';
        end

        % Callback function: AnalyseButton, idenresult
        function AnalyseButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %START OF ANALYSIS

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% CLEAR RESULTS DISPLAYED

            answer = ' ';
            app.idenresult.Value = answer;
            app.idenresult_2.Value = [];
            app.idenresult_3.Value = [];
            app.idenresult_4.Value=  [];
            app.resultsAnalysis = [];
            drawnow;

            %% USER ATTACHES OWN TXT FILE
            switch app.TypeOfAnalysisDropDown.Value
                case '1'
                    message = 'Model is being analysed... ';
                case '2'
                    message = 'Model reduction is computed...';
                    app.StrucIDOptions.kRed = app.InputNumberEditField.Value;
                    app.StrucIDOptions.Tsim = app.T_simEditField.Value;
                    app.StrucIDOptions.IncludeAdjointSens = app.IncludeAdjointSensCheckBox.Value;
                case '3'
                    message = 'Minimal Output Set is computed...';
                    app.StrucIDOptions.nyMissing = app.InputNumberEditField.Value;
            end
            app.idenresult.Value = message; drawnow;
            
            if ishandle(2), clf(2); end % clean figure last analysis
            removeStyle(app.UITablePar);            

            % pick up variables from the GUI
            app.StrucIDOptions.Tf = app.T_fEditField.Value;
            app.StrucIDOptions.IntegratorOptions = odeset('AbsTol',app.AbsToleranceEditField.Value);
            nTraject = app.TrajectoriesEditField.Value;
            app.StrucIDOptions.nTraject = nTraject; Concatenate = (nTraject>1);
            app.StrucIDOptions.AbsoluteSensitivity = app.AbsoluteSensButton.Value;
            app.StrucIDOptions.Obs = app.LocalObservabilityButton.Value;

            % set the integrator for the simulation
            value = app.IntegratorDropDown.Value;
            switch value
                case '1'
                    app.StrucIDOptions.Integrator="auto";
                case '2'
                    app.StrucIDOptions.Integrator="stiff";
                case '3'
                    app.StrucIDOptions.Integrator="nonstiff";
                case '4'
                    app.StrucIDOptions.Integrator="ode23";
                case '5'
                    app.StrucIDOptions.Integrator="ode45";
                case '6'
                    app.StrucIDOptions.Integrator="RK4";                    
                case '7'
                    app.StrucIDOptions.Integrator="ode78";
                case '8'
                    app.StrucIDOptions.Integrator="ode89";
                case '9'
                    app.StrucIDOptions.Integrator="ode113";
                case '10'
                    app.StrucIDOptions.Integrator="ode15s";
                case '11'
                    app.StrucIDOptions.Integrator="ode23t";
                case '12'
                    app.StrucIDOptions.Integrator="ode23tb";
                case '13'
                    app.StrucIDOptions.Integrator="cvodesNonstiff";
                case '14'
                    app.StrucIDOptions.Integrator="cvodesStiff";

            end

            varValues=app.UITablePar.Data{:,2};
            np=numel(app.StrucIDModel.parNames);
            app.StrucIDModel.parValues=varValues(1:np);
            app.StrucIDModel.stateValues=varValues(np+1:end);

            % if trajectories are combined, then fix the values of the
            % correlated parameters, if there are any. Else, fall back to
            % the original selection of fixed parameters
            if Concatenate % && ~isempty(app.CorParIndex))
                app.UITablePar.Data.FixedValue(app.CorParIndex)=true;
            % elseif ~(app.TypeOfAnalysisDropDown.Value=='2')
            %     app.UITablePar.Data{:,4}=app.FirstSelectionFixed;
            end

            app.StrucIDModel.UnknownVarIndex=find(app.UITablePar.Data{:,3});
            app.StrucIDModel.FixedVarIndex=find(app.UITablePar.Data{:,4});
            app.StrucIDModel.OutputSelect=find(app.UITableOutput.Data{:,3});
            app.StrucIDModel.InputSelect=find(app.UITableInput.Data{:,3});
            
            % switch app.TypeOfAnalysisDropDown.Value
            %     case {'1','2'}
            %         app.StrucIDModel.OutputSelect=find(app.UITableOutput.Data{:,3});
            %     case '3'
            %         app.StrucIDModel.OutputSelect=(1:numel(app.StrucIDModel.outputNames)); % all available sensors are considered in the MOS analysis
            % end

            % Update directed graph
            StrucIDmakedirectedgraph(app.StrucIDModel,app.StrucIDOptions); 

            [iden,numberofsing,GapSize,app.CorParIndex,app.thetaRealised,message,...
                app.resultsAnalysis] = StrucIDanalysis(app.StrucIDModel,app.StrucIDOptions);

            S1=uistyle('BackgroundColor','#94D8F4');
            if (numberofsing>0 && app.TypeOfAnalysisDropDown.Value == '1')
               ind=app.StrucIDModel.UnknownVarIndex;
                addStyle(app.UITablePar,S1,'row',ind(app.CorParIndex)); drawnow;
            end

            figure(app.StrucIDWindowUIFigure); drawnow; % put results window to the front

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% RESULTS DISPLAYED
            currentMessage=cat(2,message,sprintf('%s\n','Done.')); answer='';
            if or(app.TypeOfAnalysisDropDown.Value=='1',app.TypeOfAnalysisDropDown.Value=='3')
                switch iden
                    case 0
                        answer = 'MODEL COULD NOT BE ANALYSED - please review model defined in .txt file.';
                    case 1
                        if app.LocalControllabilityButton.Value
                            answer = 'System is LOCAL REACHABLE';
                        else
                            answer = 'System is LOCAL OBSERVABLE';
                        end
                        app.idenresult_3.Value = GapSize;
                    case 2
                        if app.LocalControllabilityButton.Value
                            answer = 'System is LOCAL UNREACHABLE';
                        else
                            answer = 'System is LOCAL UNOBSERVABLE';
                        end
                        app.idenresult_2.Value = numberofsing;
                        app.idenresult_3.Value = GapSize;
                        app.idenresult_4.Value=  numel(app.CorParIndex);
                    case 3
                        answer = '(# missing sensors) >= (# available sensors)!';
                        app.idenresult_2.Value = numberofsing;
                        app.idenresult_3.Value = GapSize;
                        app.idenresult_4.Value=  numel(app.CorParIndex);
                    case 4
                        answer = 'No Sensors Selected! Please, choose at least one sensor!';
                        app.idenresult_2.Value = numberofsing;
                        app.idenresult_3.Value = GapSize;
                        app.idenresult_4.Value=  numel(app.CorParIndex);
                end
                app.idenresult.Value = cat(2,sprintf('%s\n\n',answer),currentMessage);
            else
                app.idenresult.Value = currentMessage;
            end
            
        end

        % Button pushed function: ImportModelButton
        function ImportModelButtonPushed(app, event)

            answer = 'Choose a text or mat file that contains the model definition... ';
            app.idenresult.Value = answer; drawnow;
            removeStyle(app.UITablePar);
            app.ResultsDirectory=[];

            [filename, pathname] = uigetfile({'*.txt;*.mat'},'File Selection',app.ModelDirectory);

            % run ADiMAT startup script for automatic differentiation. This is to make
            % sure that the ADiMAT routines are available when running the sensitivity
            % computations
            if (~exist('admDiffComplex.m','file') && ~isdeployed)
                run('../adimat/ADiMat_startup');
            end

            if ~isequal(filename,0)
                [~,~,ext]=fileparts(filename);
                switch ext
                    case '.txt'
                        [model,options] = StrucIDreadmodel([pathname,filename]);
                    case '.mat'
                        load([pathname,filename],'results');
                        model=results.model;
                        options=results.options;
                end
                app.ModelDirectory=pathname;
                [~,app.StrucIDModelName,~] = fileparts(filename);

                app.idenresult.Value=['Model has been imported successfully!',newline,'Building Directed Graph...'];
                app.idenresult.Value='Model has been imported successfully!';
                StrucIDmakedirectedgraph(model,options); figure(1);

                varNames = cat(1,model.parNames,model.icNames);
                varValues = cat(1,model.parValues,model.stateValues);
                Unknown = false(size(varNames)); Unknown(model.UnknownVarIndex)=true;
                FixedValue = false(size(varNames)); FixedValue(model.FixedVarIndex)=true;
                tPar = table(varNames,varValues,Unknown,FixedValue);

                outputSelect=true(size(model.outputNames));
                tOutput = table(model.outputNames,model.EqnsTxt.YTxt,outputSelect);

                inputSelect=true(size(model.inputNames));
                tInput = table(model.inputNames,model.EqnsTxt.UTxt,inputSelect);

                % store results in App variables
                app.UITablePar.Data = tPar;
                app.UITableOutput.Data = tOutput;
                app.UITableInput.Data = tInput;

                % store first selections for later use
                app.FirstSelectionPar=Unknown;
                app.FirstSelectionFixed=FixedValue;
                app.FirstSelectionSensors=outputSelect;
                app.FirstSelectionInputs=inputSelect;
                % store first par and state values for later use
                app.FirstValuesThetaNom=app.UITablePar.Data{:,2};
            else
                app.idenresult.Value = 'Model import was cancelled.'; drawnow;
            end

            app.StrucIDModel=model;
            app.StrucIDOptions=options;

            % reset the options in the GUI
            app.AbsToleranceEditField.Value = options.IntegratorOptions.AbsTol;
            app.TrajectoriesEditField.Value = options.nTraject;
            app.T_fEditField.Value = options.Tf;
            app.IntegratorDropDown.Value = '1';
            app.LocalObservabilityButton.Value=true;
            app.AbsoluteSensButton.Value=true;
            app.TypeOfAnalysisDropDown.Value ='1';
            app.InputTextEditField.Visible = 'off';
            app.InputNumberEditField.Visible = 'off';
            app.T_simEditField.Visible = 'off';
            app.T_simEditFieldLabel.Visible = 'off';
            app.IncludeAdjointSensCheckBox.Visible = 'off';
            app.FixNaNsButton.Value=false;
            app.TypeofAnalysisButtonGroup.Visible = 'on';

            % figure(app.StrucIDWindowUIFigure); drawnow; % put main window to front
        end

        % Close request function: StrucIDWindowUIFigure
        function StrucIDWindowUIFigureCloseRequest(app, event)
            YN = uiconfirm(app.StrucIDWindowUIFigure,'Do you want to close the Struc_ID app?', 'Close request');
            if strcmpi(YN,'OK')
                close all;
                delete(app)
            end
        end

        % Value changed function: TypeOfAnalysisDropDown
        function TypeOfAnalysisDropDownValueChanged(app, event)
            value = app.TypeOfAnalysisDropDown.Value;
            model = app.StrucIDModel;
%             options = app.StrucIDOptions;
            app.StrucIDOptions.TypeOfAnalysis = value;

            if ishandle(2), close(2); end % close SVD plot previous analysis
            if ishandle(3), close(3); end % close state reduction graph
            % reset results panel when a change is made
            app.idenresult.Value='';
            app.idenresult_2.Value=0; app.idenresult_3.Value=0; app.idenresult_4.Value=0;
            
            switch value
                case '1' % structural analysis                    
                    app.UITablePar.Data.Unknown=app.FirstSelectionPar; % reset parameters to first selection (specified in input file)
                    app.UITablePar.Data.FixedValue=app.FirstSelectionFixed; % reset fixed parameters to first selection
                    app.UITableOutput.Data{:,3}=app.FirstSelectionSensors; % reset sensors to firs selection (specified in input file)
                    app.UITableInput.Data{:,3}=app.FirstSelectionInputs; % reset sensors to firs selection (specified in input file)
%                     app.UITableOutput.Data{:,3}=true; % reset sensor choice to include ALL available sensors
                    app.TypeofAnalysisButtonGroup.Visible = 'on';
                    app.InputTextEditField.Visible= 'off';
                    app.InputNumberEditField.Visible= 'off';
                    app.T_simEditField.Visible = 'off';
                    app.T_simEditFieldLabel.Visible = 'off';
                    app.IncludeAdjointSensCheckBox.Visible = 'off';
                case '2' % state reduction
                    app.idenresult.Value='State Reduction MODE.';
                    nx = numel(model.stateNames);
                    np = numel(model.parNames);
                    % set all parameters to 'known' and all states to
                    % 'unknown'
                    app.UITablePar.Data.Unknown=[false(np,1); true(nx,1)];
                    app.UITablePar.Data{1:np,4}=true(np,1);
                    app.UITableOutput.Data{:,3}=app.FirstSelectionSensors;
                    app.UITableInput.Data{:,3}=app.FirstSelectionInputs;
                    app.InputTextEditField.Value = 'Red.Dim. (kRed)';
                    app.InputNumberEditField.Value = nx;
                    app.InputTextEditField.Visible= 'on';
                    app.InputNumberEditField.Visible = 'on';
                    app.T_simEditField.Value = 3*app.T_fEditField.Value;
                    app.T_simEditField.Visible = 'on';                    
                    app.T_simEditFieldLabel.Visible = 'on';
                    app.TypeofAnalysisButtonGroup.Visible = 'off';                    
                    if numel(model.inputNames)>0
                        app.IncludeAdjointSensCheckBox.Visible = 'on';
                    end
                case '3' % MOS/MIS                    
                    app.UITablePar.Data.Unknown=app.FirstSelectionPar; % reset parameters to first selection (specified in input file)
                    app.UITableOutput.Data{:,3}=false; % uncheck all sensors when probing starts
                    app.InputNumberEditField.Value=1;
                    app.InputTextEditField.Value = '# Missing Outputs:'; 
                    app.InputTextEditField.Visible= 'on';
                    app.InputNumberEditField.Visible= 'on';
                    app.TypeofAnalysisButtonGroup.Visible = 'off';
            end
        end

        % Selection changed function: TypeofAnalysisButtonGroup
        function TypeofAnalysisButtonGroupSelectionChanged(app, event)
            observability = app.LocalObservabilityButton.Value;
            if ~observability % in case of controllability the state variables are the only unknowns
                nx = numel(app.StrucIDModel.stateNames);
                np = numel(app.StrucIDModel.parNames);
                app.UITablePar.Data{:,3}=[false(np,1); true(nx,1)];
            else
                app.UITablePar.Data{:,3}=app.FirstSelectionPar;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.ResultsDirectory)
                res = uigetdir();
                if res==0 % in case selection was cancelled
                    app.ResultsDirectory=[];
                    return
                else
                    app.ResultsDirectory=res;
                end
            end

            model = app.StrucIDModel;
            varValues=app.UITablePar.Data{:,2};
            np=numel(app.StrucIDModel.parNames);
            model.parValues=varValues(1:np);
            model.stateValues=varValues(np+1:end);
            
            % options = app.StrucIDOptions;
            results = app.resultsAnalysis;
            % save([app.ResultsDirectory,'\',app.StrucIDModelName],'model','options');
            save([app.ResultsDirectory,'\','results',app.StrucIDModelName],'results');

            fig1=figure(1);
            exportgraphics(fig1,[app.ResultsDirectory,'\DirectedGraph_',app.StrucIDModelName,'.pdf']);

            fig2=figure(2);
            switch app.TypeOfAnalysisDropDown.Value
                case '1'
                    exportgraphics(fig2,[app.ResultsDirectory,'\IDsignature_',app.StrucIDModelName,'.pdf']);
                case '2'
                    exportgraphics(fig2,[app.ResultsDirectory,'\ReducedModelOutput_',app.StrucIDModelName,'.pdf']);
            end
        end

        % Value changed function: FixNaNsButton
        function FixNaNsButtonValueChanged(app, event)
            value = app.FixNaNsButton.Value;
            if value % in case of NaNs, fix their value so that nothing changes over one run of StrucID analysis
                if ~isempty(app.thetaRealised)
                app.UITablePar.Data{:,2}=app.thetaRealised(:,end); % take last value (in case of multiple trajectories) for thetaNominal
                else
                    return
                end
            else % return values to model definition values (in txt file)
                app.UITablePar.Data{:,2}=app.FirstValuesThetaNom;                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StrucIDWindowUIFigure = uifigure('Visible', 'off');
            app.StrucIDWindowUIFigure.Name = 'StrucID Window';
            app.StrucIDWindowUIFigure.Icon = 'icon_48.png';
            app.StrucIDWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @StrucIDWindowUIFigureCloseRequest, true);
            app.StrucID_Label = uilabel(app.StrucIDWindowUIFigure);
            app.StrucID_Label.Text = 'StrucIDv3.8';
            app.Button = uibutton(app.StrucIDWindowUIFigure, 'push');
            app.Button.Icon = 'biology1.jpg';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.input = uipanel(app.StrucIDWindowUIFigure);
            app.input.Title = 'USER INPUT';
            app.AnalyseButton = uibutton(app.input, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.SensitivityType = uibuttongroup(app.input);
            app.SensitivityType.Title = 'Choose Type of Sensitvities to be Analysed';
            app.RelativeSensButton = uiradiobutton(app.SensitivityType);
            app.RelativeSensButton.Text = 'Use Relative Sensitivities';
            app.AbsoluteSensButton = uiradiobutton(app.SensitivityType);
            app.AbsoluteSensButton.Text = 'Use Absolute Sensitivites';
            app.AbsoluteSensButton.Value = true;
            app.ImportModelButton = uibutton(app.input, 'push');
            app.ImportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelButtonPushed, true);
            app.ImportModelButton.Tooltip = {'Import model from text file'};
            app.ImportModelButton.Text = 'Import Model';
            app.TypeofAnalysisButtonGroup = uibuttongroup(app.input);
            app.TypeofAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofAnalysisButtonGroupSelectionChanged, true);
            app.TypeofAnalysisButtonGroup.Title = 'Type of Analysis';
            app.LocalObservabilityButton = uiradiobutton(app.TypeofAnalysisButtonGroup);
            app.LocalObservabilityButton.Text = 'Local Observability';
            app.LocalObservabilityButton.Value = true;
            app.LocalControllabilityButton = uiradiobutton(app.TypeofAnalysisButtonGroup);
            app.LocalControllabilityButton.Text = 'Local Controllability';
            app.T_fLabel = uilabel(app.input);
            app.T_fLabel.Interpreter = 'latex';
            app.T_fLabel.Text = '$T_f$';
            app.T_fEditField = uieditfield(app.input, 'numeric');
            app.T_fEditField.Limits = [0 Inf];
            app.T_fEditField.Tooltip = {'Final integration time'};
            app.T_fEditField.Value = 1;
            app.AbsToleranceEditFieldLabel = uilabel(app.input);
            app.AbsToleranceEditFieldLabel.Text = 'Abs. Tolerance';
            app.AbsToleranceEditField = uieditfield(app.input, 'numeric');
            app.AbsToleranceEditField.Limits = [0 Inf];
            app.AbsToleranceEditField.Value = 1e-14;
            app.IntegratorDropDownLabel = uilabel(app.input);
            app.IntegratorDropDownLabel.Text = 'Integrator';
            app.IntegratorDropDown = uidropdown(app.input);
            app.IntegratorDropDown.Items = {'auto', 'stiff', 'nonstiff', 'ode23', 'ode45', 'RK4 (fixed h)', 'ode78', 'ode89', 'ode113', 'ode15s', 'ode23t', 'ode23tb', 'cvodesNonstiff', 'cvodesStiff'};
            app.IntegratorDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.IntegratorDropDown.Value = '1';
            app.TypeOfAnalysisDropDown = uidropdown(app.input);
            app.TypeOfAnalysisDropDown.Items = {'Structural Analysis', 'State Reduction', 'MOS'};
            app.TypeOfAnalysisDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TypeOfAnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeOfAnalysisDropDownValueChanged, true);
            app.TypeOfAnalysisDropDown.Value = '1';
            app.InputTextEditField = uieditfield(app.input, 'text');
            app.InputTextEditField.Editable = 'off';
            app.InputTextEditField.Visible = 'off';
            app.InputNumberEditField = uieditfield(app.input, 'numeric');
            app.InputNumberEditField.Limits = [0 Inf];
            app.InputNumberEditField.ValueDisplayFormat = '%.0f';
            app.InputNumberEditField.Visible = 'off';
            app.IncludeAdjointSensCheckBox = uicheckbox(app.input);
            app.IncludeAdjointSensCheckBox.Visible = 'off';
            app.IncludeAdjointSensCheckBox.Text = 'Include Adjoint Sens';
            app.SaveButton = uibutton(app.input, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save settings + pfd files of figures'};
            app.SaveButton.Text = 'Save';
            app.FixNaNsButton = uibutton(app.input, 'state');
            app.FixNaNsButton.ValueChangedFcn = createCallbackFcn(app, @FixNaNsButtonValueChanged, true);
            app.FixNaNsButton.Text = 'Fix NaN''s';
            app.TrajectoriesEditFieldLabel = uilabel(app.input);
            app.TrajectoriesEditFieldLabel.Text = '# Trajectories';
            app.TrajectoriesEditField = uieditfield(app.input, 'numeric');
            app.TrajectoriesEditField.Limits = [0 Inf];
            app.TrajectoriesEditField.ValueDisplayFormat = '%.0f';
            app.TrajectoriesEditField.Tooltip = {'Number of simulated trajectories'};
            app.TrajectoriesEditField.Value = 1;
            app.T_simEditFieldLabel = uilabel(app.input);
            app.T_simEditFieldLabel.Visible = 'off';
            app.T_simEditFieldLabel.Interpreter = 'latex';
            app.T_simEditFieldLabel.Text = '$T_{sim}$';
            app.T_simEditField = uieditfield(app.input, 'numeric');
            app.T_simEditField.Limits = [0 Inf];
            app.T_simEditField.Visible = 'off';
            app.T_simEditField.Tooltip = {'Simulation time reduced model'};
            app.T_simEditField.Value = 3;
            app.output = uipanel(app.StrucIDWindowUIFigure);
            app.output.Title = 'RESULT';
            app.idenresult = uitextarea(app.output);
            app.idenresult.ValueChangedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.ZeroSingularValuesEditFieldLabel = uilabel(app.output);
            app.ZeroSingularValuesEditFieldLabel.Text = '# Zero Singular Values';
            app.idenresult_2 = uieditfield(app.output, 'numeric');
            app.idenresult_2.Limits = [0 Inf];
            app.idenresult_2.RoundFractionalValues = 'on';
            app.idenresult_2.ValueDisplayFormat = '%.0f';
            app.idenresult_2.AllowEmpty = 'on';
            app.idenresult_2.Value = [];
            app.GapSizeLabel_2 = uilabel(app.output);
            app.GapSizeLabel_2.Text = 'Gap Size';
            app.idenresult_3 = uieditfield(app.output, 'numeric');
            app.idenresult_3.Limits = [0 Inf];
            app.idenresult_3.RoundFractionalValues = 'on';
            app.idenresult_3.ValueDisplayFormat = '%.1f';
            app.idenresult_3.AllowEmpty = 'on';
            app.idenresult_3.Value = [];
            app.CorrelatedParametersLabel_2 = uilabel(app.output);
            app.CorrelatedParametersLabel_2.Text = '# Correlated Parameters';
            app.idenresult_4 = uieditfield(app.output, 'numeric');
            app.idenresult_4.Limits = [0 Inf];
            app.idenresult_4.RoundFractionalValues = 'on';
            app.idenresult_4.ValueDisplayFormat = '%.0f';
            app.idenresult_4.AllowEmpty = 'on';
            app.idenresult_4.Value = [];
            app.output_2 = uipanel(app.StrucIDWindowUIFigure);
            app.output_2.Title = 'SELECT INPUTS/OUTPUTS/PARAMETERS';
            app.UITableOutput = uitable(app.output_2);
            app.UITableOutput.ColumnName = {'Output'; 'Equation'; 'Select'};
            app.UITableOutput.RowName = {};
            app.UITableOutput.ColumnEditable = [false false true];
            app.UITableInput = uitable(app.output_2);
            app.UITableInput.ColumnName = {'Input'; 'Value'; 'Select'};
            app.UITableInput.RowName = {};
            app.UITableInput.ColumnEditable = [false true true];
            app.UITablePar = uitable(app.output_2);
            app.UITablePar.ColumnName = {'Parameter Name'; 'Value'; 'Unknown'; 'Fixed'};
            app.UITablePar.RowName = {};
            app.UITablePar.ColumnEditable = [false true true true];
            % Show the figure after all components are created
            app.StrucIDWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StrucIDAppMatlab2024b_3_8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StrucIDWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StrucIDWindowUIFigure)
        end
    end
end